classdef appUIMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                            matlab.ui.Figure
        Menu                            matlab.ui.container.Menu
        UserdataPanel                   matlab.ui.container.Panel
        DropDownUserData                matlab.ui.control.DropDown
        UserDropDownLabel               matlab.ui.control.Label
        SimulatorPanel                  matlab.ui.container.Panel
        OnLabel                         matlab.ui.control.Label
        ButtonSimulatorRelax            matlab.ui.control.Button
        ButtonSimulatorWaveOut          matlab.ui.control.Button
        ButtonSimulatorWaveIn           matlab.ui.control.Button
        ButtonSimulatorPinch            matlab.ui.control.Button
        ButtonSimulatorOpen             matlab.ui.control.Button
        ButtonSimulatorFist             matlab.ui.control.Button
        ImageMyoFake                    matlab.ui.control.Image
        LampIsSimulatorOn               matlab.ui.control.Lamp
        TabGroup                        matlab.ui.container.TabGroup
        StaticTab                       matlab.ui.container.Tab
        ButtonStaticReadAndPredict      matlab.ui.control.Button
        PredictionsofmodelsPanel        matlab.ui.container.Panel
        ButtonStaticRewardNegative      matlab.ui.control.Button
        ButtonStaticRewardPositive      matlab.ui.control.Button
        LabelStaticModelsExecutionTime  matlab.ui.control.Label
        ModelIncompleteLabel            matlab.ui.control.Label
        ImageStaticPredictionIncomplete  matlab.ui.control.Image
        ModelOnlineLabel                matlab.ui.control.Label
        ImageStaticPredictionOnline     matlab.ui.control.Image
        ModelCompleteLabel              matlab.ui.control.Label
        ImageStaticPredictionComplete   matlab.ui.control.Image
        UIAxesStaticSnapshot            matlab.ui.control.UIAxes
        StreamingTab                    matlab.ui.container.Tab
        DebugButton                     matlab.ui.control.Button
        button_play                     matlab.ui.control.Button
        IsstreamingLamp                 matlab.ui.control.Lamp
        IsstreamingLampLabel            matlab.ui.control.Label
        OnlinelearningPanel             matlab.ui.container.Panel
        SaveresultsButton               matlab.ui.control.Button
        ButtonOnlineDataAugmentation    matlab.ui.control.Button
        LabelOnlineResults              matlab.ui.control.Label
        ButtonTest                      matlab.ui.control.Button
        ButtonTrainAndValidate          matlab.ui.control.Button
        ButtonOnlineAddClassAction      matlab.ui.control.Button
        UITableOnlineExpansion          matlab.ui.control.Table
        UIAxesOnlineEvolutionRecognition  matlab.ui.control.UIAxes
        UIAxesOnlineEvolutionClassification  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        is_streaming = false; % Description
        button_simulator_last_selected = [];
        history_experiments; % Description
    end
    
    methods (Access = private)
        
        function changeColorSimulatorGestureButton(app, gesture_name)
            color_selected = [0.61,0.93,1.00];
            color_normal = [0.96,0.96,0.96];
            
            if ~isempty(app.button_simulator_last_selected)
                app.button_simulator_last_selected.BackgroundColor = color_normal;
            end
            
            if gesture_name == "fist"
                app.button_simulator_last_selected = app.ButtonSimulatorFist;
            elseif gesture_name == "pinch"
                app.button_simulator_last_selected = app.ButtonSimulatorPinch;
            elseif gesture_name == "open"
                app.button_simulator_last_selected = app.ButtonSimulatorOpen;
            elseif gesture_name == "waveIn"
                app.button_simulator_last_selected = app.ButtonSimulatorWaveIn;
            elseif gesture_name == "waveOut"
                app.button_simulator_last_selected = app.ButtonSimulatorWaveOut;
            elseif gesture_name == "noGesture"
                app.button_simulator_last_selected = app.ButtonSimulatorRelax;
            end
            
            app.button_simulator_last_selected.BackgroundColor = color_selected;
            
            
        end
        
        function setUserData(app, user_id)
            
            global path_root user_folder
            global userData orientation user_full_dir
            global myoObject
            
            myoObject.myoData.setUserSource(user_id);
            
            user_folder = "user"+user_id;
            is_legacy = false;
            userData = loadUserByNameAndDir(user_folder, char(user_full_dir), is_legacy);
            rng('default');
            orientation = getOrientation(userData, user_folder);
            
            global window_size stride params
            
            window_size = 300;
            stride = 30;
            params.window_size = window_size;
            params.stride = stride;
            
            % models
            global model_complete model_incomplete model_incomplete_copy context_for_model_complete context_for_model_incomplete;
            

            model_complete = load(path_root + "App/matlab/data/models_complete/userData" + user_id + "_modelComplete.mat");
            model_incomplete = load(path_root + "App/matlab/data/models_incomplete/userData" + user_id + "_modelNoPinchNoOpen.mat");
            model_incomplete_copy = load(path_root + "App/matlab/data/models_incomplete/userData" + user_id + "_modelNoPinchNoOpen.mat");

            context_for_model_complete = generateContext(params, model_complete.classes_num_to_name);
            context_for_model_incomplete = generateContext(params, model_incomplete.classes_num_to_name);

            
            app.history_experiments(user_folder) = struct("classification", [], "recognition", []);
            
            
            app.onlineExpandAndTransfer(null(1));
            
            app.onlineQNNTest();
            myoObject.myoData.setSimulationRandom();
            app.staticReadAndPredict(null(1));
            
        end
        
        function giveFeedback(app, reward)
            global qnn_online context_online
            global last_sample last_prediction
            global window_size stride
            global myoObject
            
            
            features_name = "features_per_windowWin" + window_size + "Stride" + stride;
  
            states = last_sample.(features_name);
            num_windows = size(states, 1);
            
            gt_gestures_pts=zeros([1,num_windows]);
            gt_gestures_pts(1,1)=window_size;

            for k = 1:num_windows-1
                gt_gestures_pts(1,k+1)=gt_gestures_pts(1,k)+stride;
            end
        
            classes_name_to_num = context_online('classes_name_to_num');
            gt_gestures_labels = mapGroundTruthToLabelsWithPts(gt_gestures_pts, last_sample.groundTruthIndex, last_prediction, 0.2);
            gt_gestures_labels_num = mapGestureLabelsToNumbers(num_windows, gt_gestures_labels, classes_name_to_num);

            % only is labeled the ground truth part
            
            action_no_gesture = classes_name_to_num("noGesture");
            
            for index_state=1:num_windows
                action = gt_gestures_labels_num(index_state);
                if action == action_no_gesture
                    % we dont know if was correct or incorrect
                    continue;
                end
                state = states(index_state, :);
                new_state = state;
                is_terminal = index_state == num_windows;
                qnn_online.saveExperienceReplay(state', action, reward, new_state', is_terminal)
                
            end
            
            app.learnFromTransferedReplay(null(1));


        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global deviceType myoObject
            global use_online
            use_online = false;
            
            deviceType = DeviceName.myo;
            [myoObject, ~] = connectMyo("fake");
            app.LampIsSimulatorOn.Enable = true;
            
            app.history_experiments = containers.Map();
            
            global path_to_framework path_root
            path_root = "/home/magody/programming/MATLAB/tesis/";

            global user_full_dir sample_time_ms
            
            user_full_dir = path_root +"Data/preprocessing/";


            sample_time_ms = 990;
            addpath(genpath(path_root + "ModelingAndExperiments/RLSetup"));
            addpath(genpath(path_root + "GeneralLib"));
            addpath(genpath(path_root + "App/matlab/lib/Myo"));
            addpath(path_root + "App/matlab/lib/TimersHandle");
            addpath(genpath(path_root + "App/matlab/utils/timers"));
            addpath(path_root + "App/matlab/models/device");
            addpath(genpath(path_root + "App/matlab/data"));
            addpath(genpath(path_root + "App/matlab/media"));
            path_to_framework = "/home/magody/programming/MATLAB/deep_learning_from_scratch/magody_framework";% "C:\Users\Magody\Documents\GitHub\MATLABMagodyFramework\magody_framework"; "/home/magody/programming/MATLAB/deep_learning_from_scratch/magody_framework";
            addpath(genpath(path_to_framework));


            app.UITableOnlineExpansion.ColumnFormat = {'char', 'numeric'};
            app.UITableOnlineExpansion.Data = {'pinch', 5};

            num_users = 306;
            items_user = cell([1, num_users]);
            items_data_user = zeros([1, num_users]);
            for user_id=1:num_users
                items_user{user_id} =  ['user' num2str(user_id)];
                items_data_user(user_id) = user_id;
            end
            app.DropDownUserData.Items = items_user;
            app.DropDownUserData.ItemsData = items_data_user;

            
            app.setUserData(1);
        end

        % Button pushed function: button_play
        function togglePlay(app, event)
            global myoObject h plot_call_counter;
            if app.is_streaming
                myoObject.myoData.stopStreaming();
                stop(timerfindall);
                app.is_streaming = false;
                app.button_play.Icon = 'button_play.png';
            else
                myoObject.myoData.startStreaming();
                app.is_streaming = true;
                app.button_play.Icon = 'button_pause.png';

                clf();
                h = animatedline;


                plot_call_counter = 0;
                timer_plotAnimatedEMG = timer('Name', 'timer_plotAnimatedEMG', 'TimerFcn', @timerPlotAnimatedEMG, ...
                    'ExecutionMode', 'fixedRate', 'Period', 0.2);
                start(timer_plotAnimatedEMG);

                timer_snapshotEMG = timer('Name', 'timer_snapshotEMG', 'TimerFcn', @timerSnapshotEMG, ...
                    'ExecutionMode', 'fixedRate', 'Period', 1);
                start(timer_snapshotEMG);
            end


            app.IsstreamingLamp.Enable = app.is_streaming;
        end

        % Button pushed function: DebugButton
        function debug(app, event)


        end

        % Button pushed function: ButtonSimulatorFist
        function simulateFist(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("fist");
            
            app.changeColorSimulatorGestureButton("fist");
        end

        % Button pushed function: ButtonSimulatorPinch
        function simulatePinch(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("pinch");
            app.changeColorSimulatorGestureButton("pinch");
        end

        % Button pushed function: ButtonSimulatorOpen
        function simulateOpen(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("open");
            app.changeColorSimulatorGestureButton("open");
        end

        % Button pushed function: ButtonSimulatorRelax
        function simulateRelax(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("noGesture");
            app.changeColorSimulatorGestureButton("noGesture");
        end

        % Button pushed function: ButtonSimulatorWaveIn
        function simulateWaveIn(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("waveIn");
            app.changeColorSimulatorGestureButton("waveIn");
        end

        % Button pushed function: ButtonSimulatorWaveOut
        function simulateWaveOut(app, event)
            global myoObject
            myoObject.myoData.setSimulationGestures("waveOut");
            app.changeColorSimulatorGestureButton("waveOut");
        end

        % Button pushed function: ButtonStaticReadAndPredict
        function staticReadAndPredict(app, event)
            global deviceType myoObject sample_time_ms path_root
            global window_size stride orientation
            global model_complete model_incomplete
            global context_for_model_complete context_for_model_incomplete
            global last_sample last_prediction
            global qnn_online use_online context_online 
            
            number_gestures_to_simulate = 1;
            number_integer = floor(number_gestures_to_simulate);
            excedent = number_gestures_to_simulate - number_integer;
            if number_integer >= 1
                if number_integer == 1
                    wait_simulation = 0.9;
                else
                    wait_simulation = 0.9 + ((0.1 + 0.9) * number_integer - 1);
                end
            else
                wait_simulation = -0.2* excedent;
            end
            wait_simulation = wait_simulation +  (0.1 + 0.9) * excedent;

            if deviceType == DeviceName.myo
                myoObject.myoData.stopStreaming();
                myoObject.myoData.clearLogs();
                myoObject.myoData.startStreaming();
                % start executes inmediately
            end
            pause(wait_simulation);
            myoObject.myoData.stopStreaming();

            emg_stored_length = size(myoObject.myoData.emg_log, 1);
            sample_begin = max(1, emg_stored_length-sample_time_ms+1);

            emg = myoObject.myoData.emg_log(sample_begin:emg_stored_length, :);


            tic_toc_feature_extraction = tic;
            features_per_window = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg);
            time_used_for_feature_extraction_ms = toc(tic_toc_feature_extraction) * 1000;


            features_name = "features_per_windowWin" + window_size + "Stride" + stride;

            groundTruthIndex = getMuscularActivityGroundTruth(emg);
            
            last_sample = struct("emg", emg, features_name, features_per_window, "groundTruthIndex", groundTruthIndex);
            
            
            context_for_model_complete('user_gestures_testing') = {last_sample};
            context_for_model_incomplete('user_gestures_testing') = {last_sample};

            tic_toc_qnn_prediction = tic;
            history_test_complete = model_complete.q_neural_network.runEpisodes(@getRewardEMG, 3, context_for_model_complete, 0);
            history_test_incomplete = model_incomplete.q_neural_network.runEpisodes(@getRewardEMG, 3, context_for_model_incomplete, 0);
            if use_online
                context_online('user_gestures_testing') = {last_sample};
                history_test_online = qnn_online.runEpisodes(@getRewardEMG, 3, context_online, 0);  
            end
            time_used_for_qnn_prediction_ms = toc(tic_toc_qnn_prediction) * 1000 ;

            time_used_total_ms = time_used_for_feature_extraction_ms + time_used_for_qnn_prediction_ms;

            text_time = sprintf("Feature Extraction: %.2f[ms] (%.1f %%)\nPrediction: %.2f[ms] (%.1f %%)", ...
                time_used_for_feature_extraction_ms, ...
                100*time_used_for_feature_extraction_ms/time_used_total_ms, ...
                time_used_for_qnn_prediction_ms, ...
                100*time_used_for_qnn_prediction_ms/time_used_total_ms);


            app.LabelStaticModelsExecutionTime.Text = text_time;

            model_class_prediction_complete = string(history_test_complete.history_responses{1}.class);
            model_class_prediction_incomplete = string(history_test_incomplete.history_responses{1}.class);

            mapGestureLabelsToImages = containers.Map(...
                ["noGesture", "fist", "open", "pinch", "waveOut", "waveIn"],...
                ["relax.png", "fist.png", "open.png", "pinch.png", "waveOut.png", "waveIn.png"]);

            app.ImageStaticPredictionComplete.ImageSource = mapGestureLabelsToImages(model_class_prediction_complete);
            app.ImageStaticPredictionIncomplete.ImageSource = mapGestureLabelsToImages(model_class_prediction_incomplete);

            if use_online
                model_class_prediction_online = string(history_test_online.history_responses{1}.class);
                last_prediction = model_class_prediction_online;
                app.ImageStaticPredictionOnline.ImageSource = mapGestureLabelsToImages(model_class_prediction_online);

            end
            plot(app.UIAxesStaticSnapshot, emg);
            rectangle(app.UIAxesStaticSnapshot, 'Position',[groundTruthIndex(1) -1  groundTruthIndex(2)-groundTruthIndex(1) 2],'EdgeColor','g')


        end

        % Button pushed function: ButtonOnlineAddClassAction
        function onlineAddClassAction(app, event)
            [m, n] = size(app.UITableOnlineExpansion.Data);
            data_copy = app.UITableOnlineExpansion.Data;
            data_new = cell([m+1, n]);
            data_new(1:m, 1:n) = data_copy;
            app.UITableOnlineExpansion.Data = data_new;
            
        end

        % Callback function
        function onlineExpandAndTransfer(app, event)
            global qnn_online use_online context_online 
            global known_dataset_training known_dataset_test
            global userData params
            global model_incomplete_copy
            global new_class
            
            
            data_table = app.UITableOnlineExpansion.Data;
            new_class = string(data_table{1, 1});
            new_action = data_table{1, 2};
            
            classes_to_expand = 1;

            output_neurons_new = prod(model_incomplete_copy.q_neural_network.sequential_network.shape_output) + classes_to_expand;

            sequential_network_length = length(model_incomplete_copy.q_neural_network.sequential_network.network);
            network_new = cell([1, sequential_network_length]);
            
            temp = model_incomplete_copy.q_neural_network.sequential_network.network(:);
            for i=1:sequential_network_length-1
                network_new{1, i} = temp{i};
            end
            clear temp;
            
            % last layer should be new due to different neurons
            network_new(sequential_network_length) = {Dense(output_neurons_new, "xavier")};
            
                        
            qnn_online = QNeuralNetwork(Sequential({}), Sequential(network_new), model_incomplete_copy.q_neural_network.nnConfig, model_incomplete_copy.q_neural_network.qLearningConfig, model_incomplete_copy.q_neural_network.functionExecuteEpisode);
            qnn_online.transferGameReplay(model_incomplete_copy.q_neural_network.gameReplay);
            qnn_online.setCustomRunEpisodes(@customRunEpisodesEMG);
            use_online = true;
            
            
            classes_num_to_name = deepCopyMap(model_incomplete_copy.classes_num_to_name, 'double', 'char');
            classes_num_to_name(new_action) = new_class;
            context_online = generateContext(params, classes_num_to_name);
            
            dataset_complete = userData.training;
            dataset_complete_test = userData.testing;
            known_dataset_training = {};
            known_dataset_test = {};
            known_classes = string(values(model_incomplete_copy.classes_num_to_name));
            known_classes_test = string(values(classes_num_to_name));
            
            for index_dataset_complete=1:length(dataset_complete)
                gesture = dataset_complete{index_dataset_complete}.gestureName;
                gesture_test = dataset_complete_test{index_dataset_complete}.gestureName;
                if searchStringInArray(known_classes, gesture) ~= -1
                    known_dataset_training = [known_dataset_training, dataset_complete(index_dataset_complete)]; %#ok<AGROW> 
                end
                if searchStringInArray(known_classes_test, gesture_test) ~= -1
                    known_dataset_test = [known_dataset_test, dataset_complete_test(index_dataset_complete)]; %#ok<AGROW> 
                end
            end
            
            app.onlineQNNTest();

            % uiwait(msgbox(sprintf("Expansion and Transfer learning complete"),'Success','modal'));
        end

        % Button pushed function: ButtonTest
        function onlineQNNTest(app, event)
            global qnn_online context_online
            global known_dataset_test
            global user_folder
            
            context_online('user_gestures_validation') = [known_dataset_test];
            
            [summary, ~] = ExperimentHelper.testModelIndividual(qnn_online, true, context_online, 0);
        
            classification = summary.classification_test*100;
            recognition = summary.recognition_test*100;
        
        
            app.LabelOnlineResults.Text = sprintf("TEST (%d samples):\nClassification: %.2f %%\nRecognition: %.2f %%", ...
                length(known_dataset_test), ...
                classification, ...
                recognition);
            
            history_user = app.history_experiments(user_folder);
            history_user.classification = [history_user.classification, classification];
            history_user.recognition = [history_user.recognition, recognition];
            
            app.history_experiments(user_folder) = history_user;
            plot(app.UIAxesOnlineEvolutionClassification, history_user.classification);
            plot(app.UIAxesOnlineEvolutionRecognition, history_user.recognition);
            
            
        end

        % Button pushed function: ButtonTrainAndValidate
        function onlineQNNTrain(app, event)
            global qnn_online context_online
            global known_dataset_training
            global path_to_framework path_root
            
            known_dataset_training = known_dataset_training(randperm(numel(known_dataset_training)));

            known_dataset_training_length = length(known_dataset_training);
            
            index_end_training = known_dataset_training_length - 25;
            
            do_validation = true;
            context_online('user_gestures_training') = known_dataset_training(1:index_end_training);
            if do_validation
                context_online('user_gestures_validation') = known_dataset_training((index_end_training+1):known_dataset_training_length);
            end
            
    
            general_epochs = 5;
            [~, summary, ~] = trainAndValidate(path_to_framework, path_root, qnn_online, ...
                                                general_epochs, do_validation, context_online, 1);

            
            app.onlineQNNTest();

            uiwait(msgbox(sprintf("Finish train, %.2f, %.2f", ...
                summary{general_epochs, 2}.classification_validation.accuracy, ...
                summary{general_epochs, 2}.recognition_validation.accuracy),'Success','modal'));
            
            
        end

        % Button pushed function: ButtonStaticRewardPositive
        function feedbackPositive(app, event)
            app.giveFeedback(1);
        end

        % Button pushed function: ButtonStaticRewardNegative
        function feedbackNegative(app, event)
            app.giveFeedback(-1);
        end

        % Callback function
        function learnFromTransferedReplay(app, event)
            global qnn_online
            
            for epoch=1:10
                qnn_online.learnFromExperienceReplay(epoch, 0);
            end
        end

        % Button pushed function: ButtonOnlineDataAugmentation
        function onlineAddRepresentative(app, event)
            global known_dataset_training
            global last_sample new_class
            global window_size stride
            global path_root orientation
            
            last_sample.gestureName = new_class;
            
            features_name = "features_per_windowWin" + window_size + "Stride" + stride;


            data_augmentation_moving_avg_box = {};
            for i=2:15
                emg_new = movmean(last_sample.emg, i);
                features_per_window = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg_new);
                
                data_augmentation_moving_avg_box = [data_augmentation_moving_avg_box, ...
                    {struct("emg", emg_new, ...
                        features_name, features_per_window, ...
                        "groundTruthIndex", last_sample.groundTruthIndex, ...
                        "gestureName", last_sample.gestureName)}]
                
            end
            
            data_augmentation_shift = {};
            for i=1:5
                shift = i*20;
                emg_length = size(last_sample.emg, 1);
                emg_new_left = zeros(size(last_sample.emg));
                emg_new_right = zeros(size(last_sample.emg));
                emg_new_left(shift:emg_length, :) = last_sample.emg(1:(emg_length-shift+1), :);
                groundTruthLeft = last_sample.groundTruthIndex + shift;
                
                emg_new_right(1:(emg_length-shift+1), :) = last_sample.emg(shift:emg_length, :);
                groundTruthRight = last_sample.groundTruthIndex - shift;
                
                features_per_window_left = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg_new_left);
                
                data_augmentation_shift = [data_augmentation_shift, ...
                    {struct("emg", emg_new_left, ...
                    features_name, features_per_window_left, ...
                    "groundTruthIndex", groundTruthLeft, ...
                    "gestureName", last_sample.gestureName)}]
                
                features_per_window_right = extractFeaturesByWindowStride(path_root, orientation, window_size, stride, emg_new_right);
                
                data_augmentation_shift = [data_augmentation_shift, ...
                    {struct("emg", emg_new_right, ...
                    features_name, features_per_window_right, ...
                    "groundTruthIndex", groundTruthRight, ...
                    "gestureName", last_sample.gestureName)}]
                
            end
            
            known_dataset_training = [known_dataset_training, ...
                {last_sample}, ...
                data_augmentation_moving_avg_box, ...
                data_augmentation_shift];
            
            app.onlineQNNTrain(null(1));
            
            app.onlineQNNTest();
        end

        % Value changed function: DropDownUserData
        function changeUserData(app, event)
            value = app.DropDownUserData.Value;
            app.setUserData(value);
        end

        % Button pushed function: SaveresultsButton
        function saveOnlineResults(app, event)
            global path_root
            model_dir = path_root + "App/matlab/data/saves/save_" + string(datetime());
            history_experiments = app.history_experiments; %#ok<ADPROPLC> 
            save(model_dir, "history_experiments");
            uiwait(msgbox(sprintf("Saved"),'Success','modal'));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'MATLAB App';
            app.Menu = uimenu(app.main);
            app.Menu.Text = 'Menu';
            app.OnlinelearningPanel = uipanel(app.main);
            app.OnlinelearningPanel.Title = 'Online learning';
            app.UIAxesOnlineEvolutionClassification = uiaxes(app.OnlinelearningPanel);
            title(app.UIAxesOnlineEvolutionClassification, 'Classification')
            xlabel(app.UIAxesOnlineEvolutionClassification, 'X')
            ylabel(app.UIAxesOnlineEvolutionClassification, 'Y')
            zlabel(app.UIAxesOnlineEvolutionClassification, 'Z')
            app.UIAxesOnlineEvolutionRecognition = uiaxes(app.OnlinelearningPanel);
            title(app.UIAxesOnlineEvolutionRecognition, 'Recognition')
            xlabel(app.UIAxesOnlineEvolutionRecognition, 'X')
            ylabel(app.UIAxesOnlineEvolutionRecognition, 'Y')
            zlabel(app.UIAxesOnlineEvolutionRecognition, 'Z')
            app.UITableOnlineExpansion = uitable(app.OnlinelearningPanel);
            app.UITableOnlineExpansion.ColumnName = {'Class'; 'Action'};
            app.UITableOnlineExpansion.RowName = {};
            app.UITableOnlineExpansion.ColumnEditable = true;
            app.ButtonOnlineAddClassAction = uibutton(app.OnlinelearningPanel, 'push');
            app.ButtonOnlineAddClassAction.ButtonPushedFcn = createCallbackFcn(app, @onlineAddClassAction, true);
            app.ButtonOnlineAddClassAction.Text = '+';
            app.ButtonTrainAndValidate = uibutton(app.OnlinelearningPanel, 'push');
            app.ButtonTrainAndValidate.ButtonPushedFcn = createCallbackFcn(app, @onlineQNNTrain, true);
            app.ButtonTrainAndValidate.Text = 'Train1';
            app.ButtonTest = uibutton(app.OnlinelearningPanel, 'push');
            app.ButtonTest.ButtonPushedFcn = createCallbackFcn(app, @onlineQNNTest, true);
            app.ButtonTest.Text = 'Test';
            app.LabelOnlineResults = uilabel(app.OnlinelearningPanel);
            app.LabelOnlineResults.Text = 'Results';
            app.ButtonOnlineDataAugmentation = uibutton(app.OnlinelearningPanel, 'push');
            app.ButtonOnlineDataAugmentation.ButtonPushedFcn = createCallbackFcn(app, @onlineAddRepresentative, true);
            app.ButtonOnlineDataAugmentation.Text = 'Train2';
            app.SaveresultsButton = uibutton(app.OnlinelearningPanel, 'push');
            app.SaveresultsButton.ButtonPushedFcn = createCallbackFcn(app, @saveOnlineResults, true);
            app.SaveresultsButton.Text = 'Save results';
            app.TabGroup = uitabgroup(app.main);
            app.StaticTab = uitab(app.TabGroup);
            app.StaticTab.Title = 'Static';
            app.UIAxesStaticSnapshot = uiaxes(app.StaticTab);
            title(app.UIAxesStaticSnapshot, 'EMG Snapshot')
            xlabel(app.UIAxesStaticSnapshot, 'Time [ms]')
            ylabel(app.UIAxesStaticSnapshot, {'A'; ''})
            zlabel(app.UIAxesStaticSnapshot, 'Z')
            app.PredictionsofmodelsPanel = uipanel(app.StaticTab);
            app.PredictionsofmodelsPanel.Title = 'Predictions of models';
            app.ImageStaticPredictionComplete = uiimage(app.PredictionsofmodelsPanel);
            app.ImageStaticPredictionComplete.ImageSource = 'unknown.png';
            app.ModelCompleteLabel = uilabel(app.PredictionsofmodelsPanel);
            app.ModelCompleteLabel.Text = {'Model'; 'Complete'};
            app.ImageStaticPredictionOnline = uiimage(app.PredictionsofmodelsPanel);
            app.ImageStaticPredictionOnline.ImageSource = 'unknown.png';
            app.ModelOnlineLabel = uilabel(app.PredictionsofmodelsPanel);
            app.ModelOnlineLabel.Text = {'Model'; 'Online'};
            app.ImageStaticPredictionIncomplete = uiimage(app.PredictionsofmodelsPanel);
            app.ImageStaticPredictionIncomplete.ImageSource = 'unknown.png';
            app.ModelIncompleteLabel = uilabel(app.PredictionsofmodelsPanel);
            app.ModelIncompleteLabel.Text = {'Model'; 'Incomplete'};
            app.LabelStaticModelsExecutionTime = uilabel(app.PredictionsofmodelsPanel);
            app.LabelStaticModelsExecutionTime.Text = '0 [ms]';
            app.ButtonStaticRewardPositive = uibutton(app.PredictionsofmodelsPanel, 'push');
            app.ButtonStaticRewardPositive.ButtonPushedFcn = createCallbackFcn(app, @feedbackPositive, true);
            app.ButtonStaticRewardPositive.Icon = 'reward_positive.png';
            app.ButtonStaticRewardPositive.Text = '';
            app.ButtonStaticRewardNegative = uibutton(app.PredictionsofmodelsPanel, 'push');
            app.ButtonStaticRewardNegative.ButtonPushedFcn = createCallbackFcn(app, @feedbackNegative, true);
            app.ButtonStaticRewardNegative.Icon = 'reward_negative.png';
            app.ButtonStaticRewardNegative.Text = '';
            app.ButtonStaticReadAndPredict = uibutton(app.StaticTab, 'push');
            app.ButtonStaticReadAndPredict.ButtonPushedFcn = createCallbackFcn(app, @staticReadAndPredict, true);
            app.ButtonStaticReadAndPredict.Text = 'Read Myo and predict';
            app.StreamingTab = uitab(app.TabGroup);
            app.StreamingTab.Title = 'Streaming';
            app.IsstreamingLampLabel = uilabel(app.StreamingTab);
            app.IsstreamingLampLabel.Text = 'Is streaming';
            app.IsstreamingLamp = uilamp(app.StreamingTab);
            app.IsstreamingLamp.Enable = 'off';
            app.button_play = uibutton(app.StreamingTab, 'push');
            app.button_play.ButtonPushedFcn = createCallbackFcn(app, @togglePlay, true);
            app.button_play.Icon = 'button_play.png';
            app.button_play.Text = '';
            app.DebugButton = uibutton(app.StreamingTab, 'push');
            app.DebugButton.ButtonPushedFcn = createCallbackFcn(app, @debug, true);
            app.DebugButton.Text = 'Debug';
            app.SimulatorPanel = uipanel(app.main);
            app.SimulatorPanel.Title = 'Simulator';
            app.LampIsSimulatorOn = uilamp(app.SimulatorPanel);
            app.LampIsSimulatorOn.Enable = 'off';
            app.ImageMyoFake = uiimage(app.SimulatorPanel);
            app.ImageMyoFake.ImageSource = 'MyoFake.png';
            app.ButtonSimulatorFist = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorFist.ButtonPushedFcn = createCallbackFcn(app, @simulateFist, true);
            app.ButtonSimulatorFist.Icon = 'fist.png';
            app.ButtonSimulatorFist.Text = 'Fist';
            app.ButtonSimulatorOpen = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorOpen.ButtonPushedFcn = createCallbackFcn(app, @simulateOpen, true);
            app.ButtonSimulatorOpen.Icon = 'open.png';
            app.ButtonSimulatorOpen.Text = 'Open';
            app.ButtonSimulatorPinch = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorPinch.ButtonPushedFcn = createCallbackFcn(app, @simulatePinch, true);
            app.ButtonSimulatorPinch.Icon = 'pinch.png';
            app.ButtonSimulatorPinch.Text = 'Pinch';
            app.ButtonSimulatorWaveIn = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorWaveIn.ButtonPushedFcn = createCallbackFcn(app, @simulateWaveIn, true);
            app.ButtonSimulatorWaveIn.Icon = 'waveIn.png';
            app.ButtonSimulatorWaveIn.Text = 'WaveIn';
            app.ButtonSimulatorWaveOut = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorWaveOut.ButtonPushedFcn = createCallbackFcn(app, @simulateWaveOut, true);
            app.ButtonSimulatorWaveOut.Icon = 'waveOut.png';
            app.ButtonSimulatorWaveOut.Text = 'WaveOut';
            app.ButtonSimulatorRelax = uibutton(app.SimulatorPanel, 'push');
            app.ButtonSimulatorRelax.ButtonPushedFcn = createCallbackFcn(app, @simulateRelax, true);
            app.ButtonSimulatorRelax.Icon = 'relax.png';
            app.ButtonSimulatorRelax.Text = 'Relax';
            app.OnLabel = uilabel(app.SimulatorPanel);
            app.OnLabel.Text = 'On';
            app.UserdataPanel = uipanel(app.main);
            app.UserdataPanel.Title = 'User data';
            app.UserDropDownLabel = uilabel(app.UserdataPanel);
            app.UserDropDownLabel.Text = 'User';
            app.DropDownUserData = uidropdown(app.UserdataPanel);
            app.DropDownUserData.Items = {};
            app.DropDownUserData.ValueChangedFcn = createCallbackFcn(app, @changeUserData, true);
            app.DropDownUserData.Value = {};
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appUIMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end